classdef SynthProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SynthDSPUIFigure            matlab.ui.Figure
        SoundControlPanel           matlab.ui.container.Panel
        PhaseLabel                  matlab.ui.control.Label
        AmplitudeLabel              matlab.ui.control.Label
        UIAxesWave                  matlab.ui.control.UIAxes
        ShiftSlider                 matlab.ui.control.Slider
        AmplitudeSlider             matlab.ui.control.Slider
        DSPCourse2018SynthLabel     matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        C4Button_2                  matlab.ui.control.Button
        D4Button_2                  matlab.ui.control.Button
        C4Button                    matlab.ui.control.Button
        E4Button                    matlab.ui.control.Button
        D4Button                    matlab.ui.control.Button
        F4Button                    matlab.ui.control.Button
        G4Button                    matlab.ui.control.Button
        F4Button_2                  matlab.ui.control.Button
        A4Button                    matlab.ui.control.Button
        G4Button_2                  matlab.ui.control.Button
        B4Button                    matlab.ui.control.Button
        A4Button_2                  matlab.ui.control.Button
        B3Button                    matlab.ui.control.Button
        C5Button                    matlab.ui.control.Button
        D5Button                    matlab.ui.control.Button
        E5Button                    matlab.ui.control.Button
        C5Button_2                  matlab.ui.control.Button
        D5Button_2                  matlab.ui.control.Button
        A3Button                    matlab.ui.control.Button
        B3Button_2                  matlab.ui.control.Button
        G3Button_2                  matlab.ui.control.Button
        G3Button                    matlab.ui.control.Button
        F3Button_2                  matlab.ui.control.Button
        F3Button                    matlab.ui.control.Button
        KeyboardStateSwitchLabel    matlab.ui.control.Label
        KeyboardStateSwitch         matlab.ui.control.Switch
        E3Button                    matlab.ui.control.Button
        D3Button_2                  matlab.ui.control.Button
        D3Button                    matlab.ui.control.Button
        C3Button_2                  matlab.ui.control.Button
        C3Button                    matlab.ui.control.Button
        AmplitudeEnvelopesPanel     matlab.ui.container.Panel
        UIAxesAmp                   matlab.ui.control.UIAxes
        AttackKnob_2Label_2         matlab.ui.control.Label
        AttackKnob                  matlab.ui.control.Knob
        DecayKnob_2Label_2          matlab.ui.control.Label
        DecayKnob                   matlab.ui.control.Knob
        SustainKnob_2Label_2        matlab.ui.control.Label
        SustainKnob                 matlab.ui.control.Knob
        ReleaseKnob_2Label_2        matlab.ui.control.Label
        ReleaseKnob                 matlab.ui.control.Knob
        FrequencyEnvelopesPanel     matlab.ui.container.Panel
        UIAxesFreq                  matlab.ui.control.UIAxes
        AttackKnob_2Label_3         matlab.ui.control.Label
        AttackFreq                  matlab.ui.control.Knob
        DecayKnob_2Label_3          matlab.ui.control.Label
        DecayFreq                   matlab.ui.control.Knob
        SustainKnob_2Label_3        matlab.ui.control.Label
        SustainFreq                 matlab.ui.control.Knob
        ReleaseKnob_2Label_3        matlab.ui.control.Label
        ReleaseFreq                 matlab.ui.control.Knob
        Label                       matlab.ui.control.Label
        PassFiltersPanel            matlab.ui.container.Panel
        HighPassKnobLabel           matlab.ui.control.Label
        HighPassKnob                matlab.ui.control.Knob
        LowPassKnobLabel            matlab.ui.control.Label
        LowPassKnob                 matlab.ui.control.Knob
        HighpassSwitch              matlab.ui.control.Switch
        LowpassSwitch               matlab.ui.control.Switch
        WaveConfigButtonGroup       matlab.ui.container.ButtonGroup
        SineButton                  matlab.ui.control.RadioButton
        SawtoothButton              matlab.ui.control.RadioButton
        SquareButton                matlab.ui.control.RadioButton
        AmpADSRSwitch               matlab.ui.control.Switch
        FreqADSRSwitch              matlab.ui.control.Switch
        AudioControllerButtonGroup  matlab.ui.container.ButtonGroup
        PlayStopButton              matlab.ui.control.Button
        LoadFileButton              matlab.ui.control.Button
        Filename                    matlab.ui.control.Label
        LoadMIDICheckBox            matlab.ui.control.CheckBox
        PitchDetectionPanel         matlab.ui.container.Panel
        AlgorithmDropDownLabel      matlab.ui.control.Label
        AlgorithmDropDown           matlab.ui.control.DropDown
        FrequencyHzLabel            matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        DetectedFreq                matlab.ui.control.Label
        NoteLabel                   matlab.ui.control.Label
        Panel_3                     matlab.ui.container.Panel
        DetectedNote                matlab.ui.control.Label
        FreqLabel                   matlab.ui.control.Label
    end

    properties (Access = public)
        t; % signal values
        KeysFig; % Figure to handle key press
        LowPassVal;
        HighPassVal;
        WaveType
        VecADSR_Amp;
        VecADSR_Freq;
        VecAFS;
        Dur; % Signal length in sec
        Fs; % Samples Rate
        isPlaying = false;
        x_est;
        x_est_fs;
    end
    
    methods (Access = public)
        
        function note = Freq2Note(app, freq)
            A4 = 440;
            C0 = A4*2.^-4.75;
            name = ["C", "C#", "D", "D#", "E", "F", "F#", "G", "G#", "A", "A#", "B"];
            try
                h = round(12*log2(freq/C0))
                octave = floor(h / 12);
                n = mod(h,12) + 1;
                note = strcat(name(n),num2str(octave));
            catch
                
            end
                
        end        
    
        function PitchDetection(app, signalOutput, Fs, algorithm)
            switch(algorithm)
                case 'Zero Crossing'                    
                   detectedFreq = ZeroCrossing(signalOutput,Fs);
                case 'YIN' 
                   [time,detectedFreq] = YIN_Estimator(signalOutput,Fs);                     
                   detectedFreq = floor(detectedFreq(length(detectedFreq)/2));
                case 'ECKF'
                    if isempty(app.Filename.Text)
                        [f0_est,amp,phi1,x_est] = ECKF(signalOutput, Fs, 0, 1);
                        detectedFreq = round(f0_est(length(f0_est)/2));
                    end
            end
            try
                Freq2Note(app,detectedFreq);
                app.DetectedFreq.Text = num2str(detectedFreq);
                app.DetectedNote.Text = Freq2Note(app,detectedFreq);
            catch
                app.DetectedFreq.Text = num2str(0);
                app.DetectedNote.Text = num2str(0);
            end
        end              
        
        % handle key pressed
        function handle_KeyPushed(app,keyFreq)
                       
            [app.VecADSR_Freq] = initADSR(app,app.AttackFreq.Value,app.DecayFreq.Value,app.SustainFreq.Value,app.ReleaseFreq.Value);
            [app.VecADSR_Amp] = initADSR(app,app.AttackKnob.Value,app.DecayKnob.Value,app.SustainKnob.Value,app.ReleaseKnob.Value);
            
            % Display the frequency of the note
            app.FreqLabel.Text = num2str(keyFreq);
            
            app.VecAFS = initAFS(app,app.VecAFS(1),keyFreq,app.VecAFS(3));
            
            Amp = app.VecAFS(1); % amplitudes
            Freq = keyFreq; % frequencies
            Phase = app.VecAFS(3);
            
            soundOut = getSound(app,Amp,Freq,Phase); %Get output signal
            
            % Apply lowpass filter
            if strcmp(app.LowpassSwitch.Value, 'On')
                soundOut = lowpass(soundOut,app.LowPassVal);
            end
            
            % Apply highpass filter for the output signal
            if strcmp(app.HighpassSwitch.Value, 'On')
                soundOut = highpass(soundOut,app.HighPassVal);
            end
                        
            soundsc(soundOut,app.Fs);
            app.Filename.Text = '';
            PitchDetection(app,soundOut,app.Fs,app.AlgorithmDropDown.Value);
            plotSound(app,soundOut);
            
        end
        
        % handle Frequency ADSR changed
        function handle_ADSRFreqChanged(app)
            [ADSR] = initADSR(app,app.AttackFreq.Value,app.DecayFreq.Value,app.SustainFreq.Value,app.ReleaseFreq.Value);
            
            env = getADSR(app,ADSR(1),ADSR(2),ADSR(3),ADSR(4),app.Dur,app.Fs);
            
            plot(app.UIAxesFreq,app.t,env);
        end
        
        % handle Amplitude ADSR changed
        function handle_ADSRAmpChanged(app)
            [ADSR] = initADSR(app,app.AttackKnob.Value,app.DecayKnob.Value,app.SustainKnob.Value,app.ReleaseKnob.Value);
            
            env = getADSR(app,ADSR(1),ADSR(2),ADSR(3),ADSR(4),app.Dur,app.Fs);
            
            plot(app.UIAxesAmp,app.t,env);
        end
        
        % Calculate output signal by Amplitude, Frequency, Shift using the current envelopes values
        function WaveOut = getSound(app,Amp,Freq,Shift)
                       
            switch app.WaveType
                case 'Sine'
                    if strcmp(app.FreqADSRSwitch.Value, 'On')
                        envFreq = getADSR(app,app.VecADSR_Freq(1),app.VecADSR_Freq(2),app.VecADSR_Freq(3),app.VecADSR_Freq(4),app.Dur,app.Fs);
                        WaveOut = Amp * sin(2 * pi * Freq * envFreq .* app.t + Shift); % fourier synthesis
                    else
                        WaveOut = Amp * sin(2 * pi * Freq * app.t  + Shift); % fourier synthesis
                    end
                case 'Sawtooth'
                    if strcmp(app.FreqADSRSwitch.Value, 'On')
                        envFreq = getADSR(app,app.VecADSR_Freq(1),app.VecADSR_Freq(2),app.VecADSR_Freq(3),app.VecADSR_Freq(4),app.Dur,app.Fs);
                        WaveOut = Amp * sawtooth(2 * pi * Freq * envFreq .* app.t + Shift); % fourier synthesis
                    else
                        WaveOut = Amp * sawtooth(2 * pi * Freq .* app.t + Shift); % fourier synthesis
                    end
                case 'Square'
                    if strcmp(app.FreqADSRSwitch.Value, 'On')
                        envFreq = getADSR(app,app.VecADSR_Freq(1),app.VecADSR_Freq(2),app.VecADSR_Freq(3),app.VecADSR_Freq(4),app.Dur,app.Fs);
                        WaveOut = Amp * square(2 * pi * Freq * envFreq .* app.t + Shift); % fourier synthesis
                    else
                        WaveOut = Amp * square(2 * pi * Freq .* app.t + Shift); % fourier synthesis
                    end
            end
            
            if strcmp(app.AmpADSRSwitch.Value, 'On')
                AmpEnvelope = getADSR(app,app.VecADSR_Amp(1),app.VecADSR_Amp(2),app.VecADSR_Amp(3),app.VecADSR_Amp(4),app.Dur,app.Fs);
                WaveOut = AmpEnvelope .* WaveOut;
            end
            
        end
        
        % Get envelopes vector by values of Attack, Decay, Sustain, Release and the signal length
        function y = getADSR(app,A, D, S, R, Dur, Fs)
            
            N = Dur * Fs;
            t = [0:N-1]/Fs
            
            y = interp1([0 (0.01+A) (0.12+D) Dur - (0.601 - S) - R-0.02 Dur], [0 1 0.4 0.4 0], t,'linear'); % !!
            
        end
        
        % Get Amplitude, Frequency and Shift values in a vector by the current values selcted in the UI
        function [AFS] = initAFS(app,Amplitude, Frequency, Shift)
            Amp = Amplitude;
            Frq = Frequency;
            Shf = Shift;
            
            [AFS] = [Amp Frq Shf];
        end
        
        function handle_AFSChanged(app)
            app.VecAFS = initAFS(app,app.AmplitudeSlider.Value,str2double(app.FreqLabel.Text),app.ShiftSlider.Value);
            
            Amp = app.VecAFS(1) ; % amplitudes
            Freq = app.VecAFS(2); % frequencies
            Phase = app.VecAFS(3);
            
            soundOut = getSound(app,Amp,Freq,Phase);
            
            plotSound(app,soundOut);
        end
        
        % Get Attack, Decay, Sustain and Release values in a vector by the current values selcted in the UI
        function [ADSR] = initADSR(app,Attack,Decay,Sustain,Release)
            Atk = Attack;
            Dcy = Decay;
            Sus = Sustain;
            Rel = Release;
            
            [ADSR] = [Atk Dcy Sus Rel];
        end
        
        % Dispaly the sin wave
        function plotSound(app,y)
            plot(app.UIAxesWave,app.t,y);
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SynthDSPUIFigure.Position = [0 50 1290 629];
            app.Fs = 8196;
            app.Dur = 1;
            N = app.Dur * app.Fs;
            app.t = [0:N-1]/app.Fs;
            app.WaveType = 'Sine';
            app.LowPassVal = 0.99;
            app.HighPassVal = 0.01;
            
            app.VecAFS = initAFS(app,app.AmplitudeSlider.Value,str2double( app.FreqLabel.Text ),app.ShiftSlider.Value);
            
            [ADSR] = initADSR(app,app.AttackFreq.Value,app.DecayFreq.Value,app.SustainFreq.Value,app.ReleaseFreq.Value);
            app.VecADSR_Freq = getADSR(app,ADSR(1),ADSR(2),ADSR(3),ADSR(4),app.Dur,app.Fs);
            
            plot(app.UIAxesFreq,app.t,app.VecADSR_Freq);
            
            [ADSR] = initADSR(app,app.AttackKnob.Value,app.DecayKnob.Value,app.SustainKnob.Value,app.ReleaseKnob.Value);
            app.VecADSR_Amp = getADSR(app,ADSR(1),ADSR(2),ADSR(3),ADSR(4),app.Dur,app.Fs);
            
            plot(app.UIAxesAmp,app.t,app.VecADSR_Amp);
            
        end

        % Button pushed function: C4Button_2
        function C4Button_2Pushed(app, event)
            handle_KeyPushed(app,261.6256);
        end

        % Button pushed function: D4Button_2
        function D4Button_2Pushed(app, event)
            handle_KeyPushed(app,293.6648);
        end

        % Button pushed function: E4Button
        function E4ButtonPushed(app, event)
            handle_KeyPushed(app,329.6276);
        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
            handle_KeyPushed(app,349.2282);
        end

        % Button pushed function: G4Button
        function G4ButtonPushed(app, event)
            handle_KeyPushed(app,391.9954);
        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
            handle_KeyPushed(app,440.0000);
        end

        % Button pushed function: B4Button
        function B4ButtonPushed(app, event)
            handle_KeyPushed(app,493.8833);
        end

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)
            handle_KeyPushed(app,277.1826);
        end

        % Button pushed function: D4Button
        function D4ButtonPushed(app, event)
            handle_KeyPushed(app,311.1270);
        end

        % Button pushed function: F4Button_2
        function F4Button_2Pushed(app, event)
            handle_KeyPushed(app,369.9944);
        end

        % Button pushed function: G4Button_2
        function G4Button_2Pushed(app, event)
            handle_KeyPushed(app,415.3047);
        end

        % Button pushed function: A4Button_2
        function A4Button_2Pushed(app, event)
            handle_KeyPushed(app,466.1638);
        end

        % Value changing function: DecayKnob
        function DecayKnobValueChanged(app, event)
            handle_ADSRAmpChanged(app);
        end

        % Value changed function: AttackKnob
        function AttackKnobValueChanged(app, event)
            handle_ADSRAmpChanged(app);
        end

        % Value changed function: ReleaseKnob
        function ReleaseKnobValueChanged(app, event)
            handle_ADSRAmpChanged(app);
        end

        % Value changed function: SustainKnob
        function SustainKnobValueChanged(app, event)
            handle_ADSRAmpChanged(app);
        end

        % Button pushed function: F3Button_2
        function F3Button_2Pushed(app, event)
            handle_KeyPushed(app,174.6141);
        end

        % Button pushed function: F3Button
        function F3ButtonPushed(app, event)
            handle_KeyPushed(app,184.9972);
        end

        % Button pushed function: G3Button_2
        function G3Button_2Pushed(app, event)
            handle_KeyPushed(app,195.9977);
        end

        % Button pushed function: G3Button
        function G3ButtonPushed(app, event)
            handle_KeyPushed(app,207.6523);
        end

        % Button pushed function: A3Button
        function A3ButtonPushed(app, event)
            handle_KeyPushed(app,220.0000);
        end

        % Button pushed function: B3Button
        function B3ButtonPushed(app, event)
            handle_KeyPushed(app,246.9417);
        end

        % Button pushed function: B3Button_2
        function B3Button_2Pushed(app, event)
            handle_KeyPushed(app,233.0819);
        end

        % Button pushed function: C5Button
        function C5ButtonPushed(app, event)
            handle_KeyPushed(app,523.2511);
        end

        % Button pushed function: C5Button_2
        function C5Button_2Pushed(app, event)
            handle_KeyPushed(app,554.3653);
        end

        % Button pushed function: D5Button
        function D5ButtonPushed(app, event)
            handle_KeyPushed(app,587.3295);
        end

        % Button pushed function: D5Button_2
        function D5Button_2Pushed(app, event)
            handle_KeyPushed(app,622.2540);
        end

        % Button pushed function: E5Button
        function E5ButtonPushed(app, event)
            handle_KeyPushed(app,659.2551);
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            handle_AFSChanged(app);
        end

        % Value changed function: ShiftSlider
        function ShiftSliderValueChanged(app, event)
            handle_AFSChanged(app);
        end

        % Value changed function: AttackFreq
        function AttackFreqValueChanged(app, event)
            handle_ADSRFreqChanged(app);
        end

        % Value changed function: DecayFreq, DecayKnob
        function DecayFreqValueChanged(app, event)
            handle_ADSRFreqChanged(app);
        end

        % Value changed function: SustainFreq
        function SustainFreqValueChanged(app, event)
            handle_ADSRFreqChanged(app);
        end

        % Value changed function: ReleaseFreq
        function ReleaseFreqValueChanged(app, event)
            handle_ADSRFreqChanged(app);
        end

        % Value changed function: KeyboardStateSwitch
        function KeyboardStateSwitchValueChanged(app, event)
            value = app.KeyboardStateSwitch.Value;
            
            app.KeysFig = figure('KeyPressFcn', @KeyFcn, 'Name', 'Key Press' , 'Position',[-40,-40,0,0]); % new traditional figure with interactive features
            
            if strcmp(value , 'Off') 
                close all;
            end           
            
            % Handle keyboard press callback input to perform the sound by note
            function KeyFcn(~,event)
                
                switch event.Key
                    case 'z'
                        handle_KeyPushed(app,174.6141);
                    case 's'
                        handle_KeyPushed(app,184.9972);
                    case 'x'
                        handle_KeyPushed(app,195.9977);
                    case 'd'
                        handle_KeyPushed(app,207.6523);
                    case 'c'
                        handle_KeyPushed(app,220.0000);
                    case 'f'
                        handle_KeyPushed(app,233.0819);
                    case 'v'
                        handle_KeyPushed(app,246.9417);
                    case 'b'
                        handle_KeyPushed(app,261.6256);
                    case 'h'
                        handle_KeyPushed(app,277.1826);
                    case 'n'
                        handle_KeyPushed(app,293.6648);
                    case 'j'
                        handle_KeyPushed(app,277.1826);
                    case 'm'
                        handle_KeyPushed(app,293.6648);
                    case 'q'
                        handle_KeyPushed(app,311.1270);
                    case '2'
                        handle_KeyPushed(app,329.6276);
                    case 'w'
                        handle_KeyPushed(app,349.2282);
                    case '3'
                        handle_KeyPushed(app,369.9944);
                    case 'e'
                        handle_KeyPushed(app,391.9954);
                    case '4'
                        handle_KeyPushed(app,415.3047);
                    case 'r'
                        handle_KeyPushed(app,440.0000);
                    case 't'
                        handle_KeyPushed(app,466.1638);
                    case '6'
                        handle_KeyPushed(app,493.8833);
                    case 'y'
                        handle_KeyPushed(app,523.2511);
                    case '7'
                        handle_KeyPushed(app,554.3653);
                    case 'u'
                        handle_KeyPushed(app,587.3295);
                    case 'p'
                        clear sound;
                end
                
            end
        end

        % Value changed function: LowPassKnob
        function LowPassKnobValueChanged(app, event)
            value = app.LowPassKnob.Value;
            app.LowPassVal = value;
        end

        % Value changed function: HighPassKnob
        function HighPassKnobValueChanged(app, event)
            value = app.HighPassKnob.Value;
            app.HighPassVal = value;
        end

        % Selection changed function: WaveConfigButtonGroup
        function WaveConfigButtonGroupSelectionChanged(app, event)
            selectedButton = app.WaveConfigButtonGroup.SelectedObject;
            app.WaveType = selectedButton.Text;
        end

        % Button pushed function: PlayStopButton
        function PlayStopButtonPushed(app, event)
            
            if ~app.isPlaying
                if ~isempty(app.Filename.Text)
                    app.isPlaying = true;
                    if app.LoadMIDICheckBox.Value == true
                        
                        midi = readmidi(app.Filename.Text,0);
                        [Notes,endtime] = midiInfo(midi);
                        waveType = app.WaveType;
                        [y,Fs] = midi2audio(app,midi,44.1e3,waveType,app.VecAFS,app.VecADSR_Amp,app.VecADSR_Freq);
                        
                        % Apply lowpass filter
                        if strcmp(app.LowpassSwitch.Value, 'On')
                            y = lowpass(y,app.LowPassVal);
                        end
                        
                        % Apply highpass filter for the output signal
                        if strcmp(app.HighpassSwitch.Value, 'On')
                            y = highpass(y,app.HighPassVal);
                        end
                        
                        soundsc(y, Fs);
                        plot(app.UIAxesWave,y);
                        
                    else
                        try
                        soundsc(app.x_est,app.x_est_fs);
                        catch
                            
                        end
                    end
                else
                    app.Filename.Text = 'No File!';
                end
            else
                app.isPlaying = false;
                clear sound;
            end
        
            
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            if app.LoadMIDICheckBox.Value == true
                
                [file,path] = uigetfile('*.mid');
                if (file ~= 0)
                    app.Filename.Text = file;
                end
            else
                 [file,path] = uigetfile({'*.mp3;*.wav;'});
                            
                if (file ~= 0)
                    app.Filename.Text = file;                                       
                    
                    [y, fs]=audioread(file);  % Read audio file
                    %convert from stereo to mono
                    if(size(y,2) == 2)
                        y = y(:,2);
                    elseif(size(y,1) == 2)
                        y = y(1,:);
                    end
                    
                    %convert to a row vector
                    if(iscolumn(y) == 1)
                        y = y';
                    end   
                    
                    if  strcmp(app.AlgorithmDropDown.Value,'YIN')
                        
                        [time, f0_est] = YIN_Estimator(y,fs,1);
                        
                        figure('Name','YIN Detection');
                    elseif strcmp(app.AlgorithmDropDown.Value,'ECKF')
                        [f0_est,amp,phi1,x_est] = ECKF(y, fs, 8, 8);
                        
                        app.x_est = real(x_est);
                        app.x_est_fs = fs;
                        
                        time = (0:length(x_est)-1)/fs;
                        figure('Name','ECKF Detection');
                    end
                    plot(time, f0_est,'r');
                    grid on;
                    
                    xlabel('Time');
                    ylabel('Estimated Frequencies (Hz)');
                end
            end
        end

        % Button pushed function: E3Button
        function E3ButtonPushed(app, event)
            handle_KeyPushed(app,164.8138);
        end

        % Button pushed function: D3Button
        function D3ButtonPushed(app, event)
            handle_KeyPushed(app,155.5635);
        end

        % Button pushed function: D3Button_2
        function D3Button_2Pushed(app, event)
            handle_KeyPushed(app,146.8324);
        end

        % Button pushed function: C3Button
        function C3ButtonPushed(app, event)
            handle_KeyPushed(app,138.5913);
        end

        % Button pushed function: C3Button_2
        function C3Button_2Pushed(app, event)
            handle_KeyPushed(app,130.8128);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SynthDSPUIFigure = uifigure;
            app.SynthDSPUIFigure.Name = 'SynthDSP';
            app.SoundControlPanel = uipanel(app.SynthDSPUIFigure);
            app.SoundControlPanel.Title = 'Sound Control';
            app.PhaseLabel = uilabel(app.SoundControlPanel);
            app.PhaseLabel.Text = 'Phase';
            app.AmplitudeLabel = uilabel(app.SoundControlPanel);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.UIAxesWave = uiaxes(app.SoundControlPanel);
            app.UIAxesWave.YLim = [-1 1];
            app.UIAxesWave.XTickLabel = '';
            app.UIAxesWave.YTickLabel = '';
            app.ShiftSlider = uislider(app.SoundControlPanel);
            app.ShiftSlider.Limits = [0 6.28];
            app.ShiftSlider.MajorTicks = [];
            app.ShiftSlider.MajorTickLabels = {};
            app.ShiftSlider.ValueChangedFcn = createCallbackFcn(app, @ShiftSliderValueChanged, true);
            app.ShiftSlider.MinorTicks = [];
            app.AmplitudeSlider = uislider(app.SoundControlPanel);
            app.AmplitudeSlider.Limits = [0 1];
            app.AmplitudeSlider.MajorTicks = [];
            app.AmplitudeSlider.MajorTickLabels = {};
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.MinorTicks = [];
            app.AmplitudeSlider.Value = 1;
            app.DSPCourse2018SynthLabel = uilabel(app.SynthDSPUIFigure);
            app.DSPCourse2018SynthLabel.Text = 'DSP Course 2018 Synth';
            app.Panel = uipanel(app.SynthDSPUIFigure);
            app.C4Button_2 = uibutton(app.Panel, 'push');
            app.C4Button_2.ButtonPushedFcn = createCallbackFcn(app, @C4Button_2Pushed, true);
            app.C4Button_2.Text = 'C4';
            app.D4Button_2 = uibutton(app.Panel, 'push');
            app.D4Button_2.ButtonPushedFcn = createCallbackFcn(app, @D4Button_2Pushed, true);
            app.D4Button_2.Text = 'D4';
            app.C4Button = uibutton(app.Panel, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Text = 'C#4';
            app.E4Button = uibutton(app.Panel, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4Button.Text = 'E4';
            app.D4Button = uibutton(app.Panel, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4Button.Text = 'D#4';
            app.F4Button = uibutton(app.Panel, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Text = 'F4';
            app.G4Button = uibutton(app.Panel, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4Button.Text = 'G4';
            app.F4Button_2 = uibutton(app.Panel, 'push');
            app.F4Button_2.ButtonPushedFcn = createCallbackFcn(app, @F4Button_2Pushed, true);
            app.F4Button_2.Text = 'F#4';
            app.A4Button = uibutton(app.Panel, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Text = 'A4';
            app.G4Button_2 = uibutton(app.Panel, 'push');
            app.G4Button_2.ButtonPushedFcn = createCallbackFcn(app, @G4Button_2Pushed, true);
            app.G4Button_2.Text = 'G#4';
            app.B4Button = uibutton(app.Panel, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4Button.Text = 'B4';
            app.A4Button_2 = uibutton(app.Panel, 'push');
            app.A4Button_2.ButtonPushedFcn = createCallbackFcn(app, @A4Button_2Pushed, true);
            app.A4Button_2.Text = 'A#4';
            app.B3Button = uibutton(app.Panel, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3Button.Text = 'B3';
            app.C5Button = uibutton(app.Panel, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C5ButtonPushed, true);
            app.C5Button.Text = 'C5';
            app.D5Button = uibutton(app.Panel, 'push');
            app.D5Button.ButtonPushedFcn = createCallbackFcn(app, @D5ButtonPushed, true);
            app.D5Button.Text = 'D5';
            app.E5Button = uibutton(app.Panel, 'push');
            app.E5Button.ButtonPushedFcn = createCallbackFcn(app, @E5ButtonPushed, true);
            app.E5Button.Text = 'E5';
            app.C5Button_2 = uibutton(app.Panel, 'push');
            app.C5Button_2.ButtonPushedFcn = createCallbackFcn(app, @C5Button_2Pushed, true);
            app.C5Button_2.Text = 'C#5';
            app.D5Button_2 = uibutton(app.Panel, 'push');
            app.D5Button_2.ButtonPushedFcn = createCallbackFcn(app, @D5Button_2Pushed, true);
            app.D5Button_2.Text = 'D#5';
            app.A3Button = uibutton(app.Panel, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3Button.Text = 'A3';
            app.B3Button_2 = uibutton(app.Panel, 'push');
            app.B3Button_2.ButtonPushedFcn = createCallbackFcn(app, @B3Button_2Pushed, true);
            app.B3Button_2.Text = 'B#3';
            app.G3Button_2 = uibutton(app.Panel, 'push');
            app.G3Button_2.ButtonPushedFcn = createCallbackFcn(app, @G3Button_2Pushed, true);
            app.G3Button_2.Text = 'G3';
            app.G3Button = uibutton(app.Panel, 'push');
            app.G3Button.ButtonPushedFcn = createCallbackFcn(app, @G3ButtonPushed, true);
            app.G3Button.Text = 'G#3';
            app.F3Button_2 = uibutton(app.Panel, 'push');
            app.F3Button_2.ButtonPushedFcn = createCallbackFcn(app, @F3Button_2Pushed, true);
            app.F3Button_2.Text = 'F3';
            app.F3Button = uibutton(app.Panel, 'push');
            app.F3Button.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3Button.Text = 'F#3';
            app.KeyboardStateSwitchLabel = uilabel(app.Panel);
            app.KeyboardStateSwitchLabel.Text = 'Keyboard State';
            app.KeyboardStateSwitch = uiswitch(app.Panel, 'slider');
            app.KeyboardStateSwitch.ValueChangedFcn = createCallbackFcn(app, @KeyboardStateSwitchValueChanged, true);
            app.E3Button = uibutton(app.Panel, 'push');
            app.E3Button.ButtonPushedFcn = createCallbackFcn(app, @E3ButtonPushed, true);
            app.E3Button.Text = 'E3';
            app.D3Button_2 = uibutton(app.Panel, 'push');
            app.D3Button_2.ButtonPushedFcn = createCallbackFcn(app, @D3Button_2Pushed, true);
            app.D3Button_2.Text = 'D3';
            app.D3Button = uibutton(app.Panel, 'push');
            app.D3Button.ButtonPushedFcn = createCallbackFcn(app, @D3ButtonPushed, true);
            app.D3Button.Text = 'D#3';
            app.C3Button_2 = uibutton(app.Panel, 'push');
            app.C3Button_2.ButtonPushedFcn = createCallbackFcn(app, @C3Button_2Pushed, true);
            app.C3Button_2.Text = 'C3';
            app.C3Button = uibutton(app.Panel, 'push');
            app.C3Button.ButtonPushedFcn = createCallbackFcn(app, @C3ButtonPushed, true);
            app.C3Button.Text = 'C#3';
            app.AmplitudeEnvelopesPanel = uipanel(app.SynthDSPUIFigure);
            app.AmplitudeEnvelopesPanel.Title = 'Amplitude Envelopes';
            app.UIAxesAmp = uiaxes(app.AmplitudeEnvelopesPanel);
            app.UIAxesAmp.YLim = [0 1.2];
            app.UIAxesAmp.XTickLabel = '';
            app.UIAxesAmp.YTickLabel = '';
            app.AttackKnob_2Label_2 = uilabel(app.AmplitudeEnvelopesPanel);
            app.AttackKnob_2Label_2.Text = 'Attack';
            app.AttackKnob = uiknob(app.AmplitudeEnvelopesPanel, 'continuous');
            app.AttackKnob.Limits = [0 0.1];
            app.AttackKnob.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.AttackKnob.MajorTickLabels = {'0', '', '', '', '', '10'};
            app.AttackKnob.ValueChangedFcn = createCallbackFcn(app, @AttackKnobValueChanged, true);
            app.DecayKnob_2Label_2 = uilabel(app.AmplitudeEnvelopesPanel);
            app.DecayKnob_2Label_2.Text = 'Decay';
            app.DecayKnob = uiknob(app.AmplitudeEnvelopesPanel, 'continuous');
            app.DecayKnob.Limits = [0 0.2];
            app.DecayKnob.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1 0.12 0.14 0.16 0.18 0.2];
            app.DecayKnob.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '20'};
            app.DecayKnob.ValueChangedFcn = createCallbackFcn(app, @DecayFreqValueChanged, true);
            app.DecayKnob.ValueChangingFcn = createCallbackFcn(app, @DecayKnobValueChanged, true);
            app.SustainKnob_2Label_2 = uilabel(app.AmplitudeEnvelopesPanel);
            app.SustainKnob_2Label_2.Text = 'Sustain';
            app.SustainKnob = uiknob(app.AmplitudeEnvelopesPanel, 'continuous');
            app.SustainKnob.Limits = [0 0.6];
            app.SustainKnob.MajorTickLabels = {'0', '', '', '', '', '', '60'};
            app.SustainKnob.ValueChangedFcn = createCallbackFcn(app, @SustainKnobValueChanged, true);
            app.SustainKnob.Value = 0.3;
            app.ReleaseKnob_2Label_2 = uilabel(app.AmplitudeEnvelopesPanel);
            app.ReleaseKnob_2Label_2.Text = 'Release';
            app.ReleaseKnob = uiknob(app.AmplitudeEnvelopesPanel, 'continuous');
            app.ReleaseKnob.Limits = [0 0.2];
            app.ReleaseKnob.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1 0.12 0.14 0.16 0.18 0.2];
            app.ReleaseKnob.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '20'};
            app.ReleaseKnob.ValueChangedFcn = createCallbackFcn(app, @ReleaseKnobValueChanged, true);
            app.FrequencyEnvelopesPanel = uipanel(app.SynthDSPUIFigure);
            app.FrequencyEnvelopesPanel.Title = 'Frequency Envelopes';
            app.UIAxesFreq = uiaxes(app.FrequencyEnvelopesPanel);
            app.UIAxesFreq.YLim = [0 1.2];
            app.UIAxesFreq.XTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxesFreq.YTickLabel = '';
            app.AttackKnob_2Label_3 = uilabel(app.FrequencyEnvelopesPanel);
            app.AttackKnob_2Label_3.Text = 'Attack';
            app.AttackFreq = uiknob(app.FrequencyEnvelopesPanel, 'continuous');
            app.AttackFreq.Limits = [0 0.1];
            app.AttackFreq.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.AttackFreq.MajorTickLabels = {'0', '', '', '', '', '10'};
            app.AttackFreq.ValueChangedFcn = createCallbackFcn(app, @AttackFreqValueChanged, true);
            app.DecayKnob_2Label_3 = uilabel(app.FrequencyEnvelopesPanel);
            app.DecayKnob_2Label_3.Text = 'Decay';
            app.DecayFreq = uiknob(app.FrequencyEnvelopesPanel, 'continuous');
            app.DecayFreq.Limits = [0 0.15];
            app.DecayFreq.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1 0.12 0.15];
            app.DecayFreq.MajorTickLabels = {'0', '', '', '', '', '', '', '15'};
            app.DecayFreq.ValueChangedFcn = createCallbackFcn(app, @DecayFreqValueChanged, true);
            app.SustainKnob_2Label_3 = uilabel(app.FrequencyEnvelopesPanel);
            app.SustainKnob_2Label_3.Text = 'Sustain';
            app.SustainFreq = uiknob(app.FrequencyEnvelopesPanel, 'continuous');
            app.SustainFreq.Limits = [0 0.6];
            app.SustainFreq.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1 0.12 0.14 0.16 0.18 0.2 0.22 0.24 0.26 0.28 0.3 0.32 0.34 0.36 0.38 0.4 0.42 0.44 0.46 0.48 0.5 0.52 0.54 0.56 0.58 0.6];
            app.SustainFreq.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '60'};
            app.SustainFreq.ValueChangedFcn = createCallbackFcn(app, @SustainFreqValueChanged, true);
            app.SustainFreq.Value = 0.3;
            app.ReleaseKnob_2Label_3 = uilabel(app.FrequencyEnvelopesPanel);
            app.ReleaseKnob_2Label_3.Text = 'Release';
            app.ReleaseFreq = uiknob(app.FrequencyEnvelopesPanel, 'continuous');
            app.ReleaseFreq.Limits = [0 0.15];
            app.ReleaseFreq.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1 0.12 0.15];
            app.ReleaseFreq.MajorTickLabels = {'0', '', '', '', '', '', '', '15'};
            app.ReleaseFreq.ValueChangedFcn = createCallbackFcn(app, @ReleaseFreqValueChanged, true);
            app.Label = uilabel(app.SynthDSPUIFigure);
            app.Label.Text = '______________________________________________';
            app.PassFiltersPanel = uipanel(app.SynthDSPUIFigure);
            app.PassFiltersPanel.Title = 'Pass Filters';
            app.HighPassKnobLabel = uilabel(app.PassFiltersPanel);
            app.HighPassKnobLabel.Text = 'High Pass';
            app.HighPassKnob = uiknob(app.PassFiltersPanel, 'continuous');
            app.HighPassKnob.Limits = [0.01 0.99];
            app.HighPassKnob.MajorTickLabels = {'Min', '', '', '', '', '', '', 'Max'};
            app.HighPassKnob.ValueChangedFcn = createCallbackFcn(app, @HighPassKnobValueChanged, true);
            app.HighPassKnob.Value = 0.01;
            app.LowPassKnobLabel = uilabel(app.PassFiltersPanel);
            app.LowPassKnobLabel.Text = 'Low Pass';
            app.LowPassKnob = uiknob(app.PassFiltersPanel, 'continuous');
            app.LowPassKnob.Limits = [0.01 0.99];
            app.LowPassKnob.MajorTickLabels = {'Min', '', '', '', '', '', '', 'Max'};
            app.LowPassKnob.ValueChangedFcn = createCallbackFcn(app, @LowPassKnobValueChanged, true);
            app.LowPassKnob.Value = 0.99;
            app.HighpassSwitch = uiswitch(app.PassFiltersPanel, 'slider');
            app.LowpassSwitch = uiswitch(app.PassFiltersPanel, 'slider');
            app.WaveConfigButtonGroup = uibuttongroup(app.SynthDSPUIFigure);
            app.WaveConfigButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WaveConfigButtonGroupSelectionChanged, true);
            app.WaveConfigButtonGroup.Title = 'Wave Config';
            app.SineButton = uiradiobutton(app.WaveConfigButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.SawtoothButton = uiradiobutton(app.WaveConfigButtonGroup);
            app.SawtoothButton.Text = 'Sawtooth';
            app.SquareButton = uiradiobutton(app.WaveConfigButtonGroup);
            app.SquareButton.Text = 'Square';
            app.AmpADSRSwitch = uiswitch(app.SynthDSPUIFigure, 'slider');
            app.AmpADSRSwitch.Value = 'On';
            app.FreqADSRSwitch = uiswitch(app.SynthDSPUIFigure, 'slider');
            app.AudioControllerButtonGroup = uibuttongroup(app.SynthDSPUIFigure);
            app.AudioControllerButtonGroup.Title = 'Audio Controller';
            app.PlayStopButton = uibutton(app.AudioControllerButtonGroup, 'push');
            app.PlayStopButton.ButtonPushedFcn = createCallbackFcn(app, @PlayStopButtonPushed, true);
            app.PlayStopButton.Text = 'Play/Stop';
            app.LoadFileButton = uibutton(app.AudioControllerButtonGroup, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.Filename = uilabel(app.AudioControllerButtonGroup);
            app.Filename.Text = '';
            app.LoadMIDICheckBox = uicheckbox(app.AudioControllerButtonGroup);
            app.LoadMIDICheckBox.Text = 'Load MIDI';
            app.LoadMIDICheckBox.Value = true;
            app.PitchDetectionPanel = uipanel(app.SynthDSPUIFigure);
            app.PitchDetectionPanel.Title = 'Pitch Detection';
            app.AlgorithmDropDownLabel = uilabel(app.PitchDetectionPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.PitchDetectionPanel);
            app.AlgorithmDropDown.Items = {'Zero Crossing', 'YIN', 'ECKF'};
            app.AlgorithmDropDown.Value = 'Zero Crossing';
            app.FrequencyHzLabel = uilabel(app.PitchDetectionPanel);
            app.FrequencyHzLabel.Text = 'Frequency [Hz]';
            app.Panel_2 = uipanel(app.PitchDetectionPanel);
            app.DetectedFreq = uilabel(app.Panel_2);
            app.DetectedFreq.Text = '';
            app.NoteLabel = uilabel(app.PitchDetectionPanel);
            app.NoteLabel.Text = 'Note';
            app.Panel_3 = uipanel(app.PitchDetectionPanel);
            app.DetectedNote = uilabel(app.PitchDetectionPanel);
            app.DetectedNote.Text = '';
            app.FreqLabel = uilabel(app.SynthDSPUIFigure);
            app.FreqLabel.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SynthProject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SynthDSPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SynthDSPUIFigure)
        end
    end
end